classdef me5405_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileBrowserButton              matlab.ui.control.Button
        FileNameEditField              matlab.ui.control.EditField
        FileNameEditFieldLabel         matlab.ui.control.Label
        NoofRowsEditField              matlab.ui.control.NumericEditField
        NoofRowsEditFieldLabel         matlab.ui.control.Label
        NoofColumnsEditField           matlab.ui.control.NumericEditField
        NoofColumnsEditFieldLabel      matlab.ui.control.Label
        PleaseSettheThresholdingLevelEditField  matlab.ui.control.NumericEditField
        PleaseSettheThresholdingLevelEditFieldLabel  matlab.ui.control.Label
        OriginalImageLabel             matlab.ui.control.Label
        OriginalImage                  matlab.ui.control.Image
        YourselectionisEditFieldLabel  matlab.ui.control.Label
        YourselectionisEditField       matlab.ui.control.EditField
        SelectTaskButtonGroup          matlab.ui.container.ButtonGroup
        BinaryImageButton              matlab.ui.control.ToggleButton
        OnepixelThinImageButton        matlab.ui.control.ToggleButton
        OutlineImageButton             matlab.ui.control.ToggleButton
        SegmentImage4connectivityButton  matlab.ui.control.ToggleButton
        ArrangeImageButton             matlab.ui.control.ToggleButton
        RotateImageButton              matlab.ui.control.ToggleButton
        SegmentImage8connectivityButton  matlab.ui.control.ToggleButton
        ForImage1andImage2Label        matlab.ui.control.Label
        ForImage1OnlyLabel             matlab.ui.control.Label
        ForImage2OnlyLabel             matlab.ui.control.Label
        OutputButton                   matlab.ui.control.Button
        OutputImagePanel               matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        RecommendedThresholdingLevelLabel  matlab.ui.control.Label
        Image1chromotxt21Label         matlab.ui.control.Label
        Image2charact1txt3Label        matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        TextArea                       matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        fileID
        cols = 0; % Description
        rows = 0; % Description
        threshold % Description
        MatGrayscale % Description
        MatBinary % Description
        MatSkel % Description
        MatOutline % Description
        MatSegmented
        MatSegLabeled % Description
        MatArranged % Description
        MatRotated % Description
    end
    
    methods (Access = private)
        
        % This function first read the input file and then translate all
        % the characters to gray numbers, and return a matrix with numbers
        % from 0 to 31
        function MatGrayscale = readFile(~, filename)
            
            
            % Read the intended file with fileread, then read each line,
            % splitting by newline and carriage return characters. Then,
            % vertically concatenate it to recreate the image
            MatOriginal = regexp(fileread(filename), '\r?\n', 'split');
            MatOriginal = vertcat(MatOriginal{:});
            
            %% Task 1: Display the original image on the screen
            % Make a new matrix called MatGrayscale that is the grayscaled version of
            % MatOriginal.
            MatGrayscale = MatOriginal;
            
            % Based on ASCII, we first ensure that any strange character values that
            % are not 0-9 or A-V are changed to their corresponding extreme values of 0
            % or 31. Values inbetween 9 and A are set to 10.
            MatGrayscale(MatGrayscale >= '!' & MatGrayscale <= '/') = 0;
            MatGrayscale(MatGrayscale >= 'W' & MatGrayscale <= '~') = 31;
            MatGrayscale(MatGrayscale >= ':' & MatGrayscale <= '@') = 10;
            
            % Based on the ASCII table, we must first convert all numbers 0-9 in the
            % image which are stored as characters to their corresponding numbers. This
            % is done by minusing 48 (as 0 equates to 48 in the ASCII table, 1 to 50,
            % and so on).
            MatGrayscale(MatGrayscale >= '0' & MatGrayscale <= '9') = MatGrayscale(MatGrayscale >= '0' & MatGrayscale <= '9') - 48;
            
            % Only letters from A to V are left, so we can convert everything that is a
            % letter by minusing 55 (as A equates to 65 in the ASCII table). 
            MatGrayscale(isletter(MatGrayscale))= MatGrayscale(isletter(MatGrayscale)) - 55;
            
            % Convert to an unsinged integer matrix
            MatGrayscale = uint8(MatGrayscale);

        end
        
        function MatOutput = skelerode(app, MatInput, rows, cols)
            % The Structuring elements used for the skeletonization. The 0s
            % represent pixels that must be 0, 1s represent pixels that must be 1,
            % and 2s represent pixels which are ignored.
            SE1 = [0 0 0;2 1 2;1 1 1];
            SE2 = [2 0 0;1 1 0;2 1 2];
            SE3 = [1 2 0;1 1 0;1 2 0]; 
            SE4 = [2 1 2;1 1 0;2 0 0];
            SE5 = [1 1 1;2 1 2;0 0 0];
            SE6 = [2 1 2;0 1 1;0 0 2];
            SE7 = [0 2 1;0 1 1;0 2 1];
            SE8 = [0 0 2;0 1 1;2 1 2];
            
            % Flip the image so that 0 (black) is background for the erosion
            % operation. Also, pad the image so that there is one layer of
            % background pixels on all four sides of the image.
            MatInputFlip = logical(1 - MatInput);
            MatInputPadded = padarray(MatInputFlip, [1 1], 0);
            MatOutputPadded = MatInputPadded;
            
            % Perform a single erosion pass of the whole image using the custom
            % defined function (made by us) called performerosion. Do this pass on
            % each structuring element. 
            while (true)
                MatOutputPrevious = MatOutputPadded;
                MatOutputPadded = performerosion(app, MatOutputPadded, rows, cols, SE1);
                MatOutputPadded = performerosion(app, MatOutputPadded, rows, cols, SE2);
                MatOutputPadded = performerosion(app, MatOutputPadded, rows, cols, SE3);
                MatOutputPadded = performerosion(app, MatOutputPadded, rows, cols, SE4);
                MatOutputPadded = performerosion(app, MatOutputPadded, rows, cols, SE5);
                MatOutputPadded = performerosion(app, MatOutputPadded, rows, cols, SE6);
                MatOutputPadded = performerosion(app, MatOutputPadded, rows, cols, SE7);
                MatOutputPadded = performerosion(app, MatOutputPadded, rows, cols, SE8);
                
                % If there is no change to the image after an entire pass through
                % all 8 structuring elements is done, end the loop.
                if (MatOutputPrevious == MatOutputPadded)
                    break
                end
            end
            % Unpad the image and flip the binary input so that 1 (white) is 
            % background again, then end this function.
            MatOutput = MatOutputPadded(2:end-1,2:end-1);
            MatOutput = logical(1 - MatOutput);
        end
        
        function MatUpdated = performerosion(~, MatOutputPadded, rows, cols, StructElem)
    
            MatUpdated = MatOutputPadded;
        
            % Start from 2 to the number of rows/columns + 1 due to the extra
            % padding of the input image. x and y are the position of the center
            % pixel within the padded image
            for x = 2:(rows+1)
                for y = 2:(cols+1)
                    
                    % First, check that the center pixel is a background pixel (0),
                    % because we can skip it if it is.
                    if (MatOutputPadded(y,x) == 0)
                        continue
                    end
                    
                    % This flag is set so that, by default, the center pixel which
                    % should be a foreground pixel is changed to a background
                    % pixel.
                    flag = 0;
        
                    % Next, check each pixel in the 3x3 grid around the center
                    % pixel.  
                    for x1 = 1:3
                        for y1 = 1:3
                            
                            % CurrentPixelX and Y correlate to the position of the
                            % pixel that is being checked, but on the padded image
                            CurrentPixelX = x + x1 - 2;
                            CurrentPixelY = y + y1 - 2;
                            
                            % If the structuring element pixel that is currently
                            % being checked is 2, ignore that pixel.
                            if (StructElem(y1,x1) == 2)
                                continue
                            % Otherwise, if the structuring element pixel and the
                            % image pixel do not match, change the flag to 1 (which
                            % means that the image pixel will remain foreground).
                            elseif (StructElem(y1,x1) ~= MatOutputPadded(CurrentPixelY,CurrentPixelX))
                                flag = 1;
                            end
                        end
                    end
                    % Check to see if the flag is 0, and thus the image pixel will
                    % be changed to a background pixel.
                    if (flag == 0)
                        MatUpdated(y,x) = 0;
                    end
                end
            end
        end
        
        function MatOutline = outline(~, MatBinary, rows, cols)
            % This is done with two passes, saving the outline values in another matrix
            % MatOutline
            checkone = 0;
            checktwo = 0;
            MatOutline = zeros(cols,rows);
            MatOutline = MatOutline + 1;
            
            % For each row and column, scan for boundary numbers and input the boundary
            % into a third matrix
            for x = 1:(rows-1)
                for y = 1:cols
                    checkone = MatBinary(y,x);
                    checktwo = MatBinary(y,x+1);
                    if (checkone == 0 && checktwo == 1)
                        MatOutline(y,x) = 0;
                    elseif (checkone == 1 && checktwo == 0)
                        MatOutline(y,x+1) = 0;
                    end
                 end
            end
            for y = 1:(cols-1)
                for x = 1:rows
                    checkone = MatBinary(y,x);
                    checktwo = MatBinary(y+1,x);
                    if (checkone == 0 && checktwo == 1)
                        MatOutline(y,x) = 0;
                    elseif (checkone == 1 && checktwo == 0)
                        MatOutline(y+1,x) = 0;
                    end
                end
            end
        end
        
        function [ImgOut, c, s, t] = FourConnect(~, ImgIn)
            s = [];
            t = [];
            % Expand the image from N*N to (N+1)*(N+1) to check all the pixels of
            % the original image
            ImgIn = padarray(ImgIn, [1 1], 0);
            c = 1;
            [m, n] = size(ImgIn);
            ImgOut = zeros([m, n]);
            % From the second row and the second column to do the traversal
            % For 8-conncetivity, you need to check all surrounding pixels, like
            % ImgIn(i,j), ImgIn(i-1,j), ImgIn(i,j-1), ImgIn(i-1,j-1), and the
            % segmented parts must be less than 4-connectivity labeled parts
            for i = 2:1:m
                for j = 2:1:n
                    
                    if ImgIn(i,j) == 1 && ImgIn(i-1,j) == 0 && ImgIn(i,j-1) == 0
                        ImgOut(i,j) = c;
                        c = c + 1;
                    elseif ImgIn(i,j) == 1 && ImgIn(i-1,j) == 1 && ImgIn(i,j-1) == 0
                        ImgOut(i,j) = ImgOut(i-1,j);
                    elseif ImgIn(i,j) == 1 && ImgIn(i-1,j) == 0 && ImgIn(i,j-1) == 1
                        ImgOut(i,j) = ImgOut(i,j-1);
                    elseif ImgIn(i,j) == 1 && ImgIn(i-1,j) == 1 && ImgIn(i,j-1) == 1
                        ImgOut(i,j) = ImgOut(i-1,j);
                        % Every time you find adjacent parts with different labels,
                        % you should save their labels to 's' and 't'
                        s(end+1) = ImgOut(i-1,j);
                        t(end+1) = ImgOut(i,j-1);
                    end
                    
                end
            end
            % The output image is (N+1)*(N+1), and it needs to be shrinked to N*N,
            % and it's the Middle Image of the whole processing
            ImgOut = ImgOut(2:end-1,2:end-1);
            % c-1 is the final number of segmented parts
            c = c-1;
        end
        
        function [ImgOut, c, s, t] = EightConnect(~, ImgIn)
            s = [];
            t = [];
            % Expand the image from N*N to (N+1)*(N+1) to check all the pixels of
            % the original image
            ImgIn = padarray(ImgIn, [1 1], 0);
            c = 1;
            [m, n] = size(ImgIn);
            ImgOut = zeros([m, n]);
            % From the second row and the second column to do the traversal
            % For 8-conncetivity, you need to check all surrounding pixels, like
            % ImgIn(i,j), ImgIn(i-1,j), ImgIn(i,j-1), ImgIn(i-1,j-1), and the
            % segmented parts must be less than 4-connectivity labeled parts
            for i = 2:1:m
                for j = 2:1:(n-1)
                  
                    if ImgIn(i,j) == 1
                        Total = ImgIn(i-1,j-1)+ImgIn(i-1,j)+ImgIn(i-1,j+1)+ImgIn(i,j-1);
                        switch Total
                            case 0
                                ImgOut(i,j) = c;
                                c = c + 1;
                            case 1
                                ImgOut(i,j) = max([ImgOut(i-1,j-1),ImgOut(i-1,j),ImgOut(i-1,j+1),ImgOut(i,j-1)]);
                            case 2
                                ImgOut(i,j) = max([ImgOut(i-1,j-1),ImgOut(i-1,j),ImgOut(i-1,j+1),ImgOut(i,j-1)]);
                                temp = ImgOut;
                                t(end+1) = max([temp(i-1,j-1),temp(i-1,j),temp(i-1,j+1),temp(i,j-1)]);
                                % Set values of 0 or less to infinity so that we
                                % can find the smallest label
                                temp(temp == 0) = max([temp(i-1,j-1),temp(i-1,j),temp(i-1,j+1),temp(i,j-1)]);
                                s(end+1) = min([temp(i-1,j-1),temp(i-1,j),temp(i-1,j+1),temp(i,j-1)]);
        
                            case 3
                                ImgOut(i,j) = max([ImgOut(i-1,j-1),ImgOut(i-1,j),ImgOut(i-1,j+1),ImgOut(i,j-1)]);
                                temp = ImgOut;
                                t(end+1) = max([temp(i-1,j-1),temp(i-1,j),temp(i-1,j+1),temp(i,j-1)]);
                                temp(temp == t(end)) = max([temp(i-1,j-1),temp(i-1,j),temp(i-1,j+1),temp(i,j-1)]);
                                % As long as not all the pixels are 0 now, we can
                                % continue
                                if max([temp(i-1,j-1),temp(i-1,j),temp(i-1,j+1),temp(i,j-1)]) > 0
                                    t(end+1) = max([temp(i-1,j-1),temp(i-1,j),temp(i-1,j+1),temp(i,j-1)]);
                                    % Set values of 0 or less to infinity so that we
                                    % can find the smallest label
                                    temp(temp == 0) = max([temp(i-1,j-1),temp(i-1,j),temp(i-1,j+1),temp(i,j-1)]);
                                    s(end+1) = min([temp(i-1,j-1),temp(i-1,j),temp(i-1,j+1),temp(i,j-1)]);
                                    s(end+1) = s(end);
                                else
                                    temp(temp == 0) = max([temp(i-1,j-1),temp(i-1,j),temp(i-1,j+1),temp(i,j-1)]);
                                    s(end+1) = min([temp(i-1,j-1),temp(i-1,j),temp(i-1,j+1),temp(i,j-1)]);
                                end
                            case 4
                                ImgOut(i,j) = max([ImgOut(i-1,j-1),ImgOut(i-1,j),ImgOut(i-1,j+1),ImgOut(i,j-1)]);
                                temp = ImgOut;
                                for tempcount = 1:3
                                    t(end+1) = max([temp(i-1,j-1),temp(i-1,j),temp(i-1,j+1),temp(i,j-1)]);
                                    temp(temp == t(end)) = min([temp(i-1,j-1),temp(i-1,j),temp(i-1,j+1),temp(i,j-1)]);
                                    temp(temp == 0) = max([temp(i-1,j-1),temp(i-1,j),temp(i-1,j+1),temp(i,j-1)]);
                                    s(end+1) = min([temp(i-1,j-1),temp(i-1,j),temp(i-1,j+1),temp(i,j-1)]);
                                end
                        end
                    end
                end
            end
            % The output image is (N+1)*(N+1), and it needs to be shrunk to N*N,
            % and it's the Middle Image of the whole processing
            ImgOut = ImgOut(2:end-1,2:end-1);
            % c-1 is the final number of segmented parts
            c = c-1;
        end
        
        function [ImgOut, label] = Label(~, ImgIn, c, s, t)
            % Get the graph of 's' and 't'
            G = graph(s,t);
            % Array L is used to save segmented parts and corresponding labels
            L = zeros(2,c);
            % The first label is 1
            label = 1;
        
            for p = 1:1:c
                % The function of dfsearch is to visit all nodes (segmented parts)
                % of the middle image and check their neighbor nodes. The return of
                % dfsearch(G, p) is its neighbor nodes of the visited node
                % https://www.mathworks.com/help/matlab/ref/graph.dfsearch.html?s_tid=doc_ta
                v = dfsearch(G,p);
                L(1,p) = p; 
                % If there is no neighbor nodes of this visited node, it means this
                % segmented part has no adjacent parts with different segment
                % numbers. So this part could be labeled by a new number
                if isempty(v)
                    L(2,p) = label;
                    label = label + 1;
                % If this visited node has neighbor nodes
                else
                    % Check if this node has been labeled, L(2,p) == 0 means it has
                    % no label. If this node has a label, then check the next one.
                    if L(2,p) == 0
                        % 'd' is the number of neighbor nodes
                        d = length(v);
                        % Every neighbor nodes is labeled by the same number
                        for q = 1:d
                            L(1,v(q)) = v(q);
                            L(2,v(q)) = label;
                        end
                    label = label + 1;
                    end
                end
            end
            
            [m, n] = size(ImgIn);
            ImgOut = zeros([m,n]);
            label = label -1;
           
            % Check the original image (middle image) and give every segmented
            % parts their corresponding labels
            for i = 1:1:m
                for j = 1:1:n
                    if ImgIn(i,j) ~= 0
                        a = ImgIn(i,j);
                        ImgOut(i,j) = L(2,a);
                    end
                end
            end
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FileBrowserButton
        function FileBrowserButtonPushed(app, event)
            [app.fileID, ~] = uigetfile('*.txt');
            app.FileNameEditField.Value = app.fileID;
            app.TextArea.Value = '';
            app.MatGrayscale = readFile(app, app.fileID);
            if(strcmp(app.fileID, 'chromo.txt'))
                app.YourselectionisEditField.Value = 'Image 1';
            elseif(strcmp(app.fileID, 'charact1.txt'))
                app.YourselectionisEditField.Value = 'Image 2';
            else
                app.YourselectionisEditField.Value = 'is wrong';
            end
            [app.rows,app.cols] = size(app.MatGrayscale);
            app.OriginalImage.ImageSource = repmat(mat2gray(app.MatGrayscale,[0,31]), 1, 1, 3);
            app.NoofColumnsEditField.Value = app.cols;
            app.NoofRowsEditField.Value = app.rows;
            
        end

        % Value changed function: 
        % PleaseSettheThresholdingLevelEditField
        function PleaseSettheThresholdingLevelEditFieldValueChanged(app, event)
            value = app.PleaseSettheThresholdingLevelEditField.Value;
            app.threshold = value;
            app.MatBinary = (app.MatGrayscale >= app.threshold);
        end

        % Button pushed function: OutputButton
        function OutputButtonPushed(app, event)
            % Binary image
            if (app.BinaryImageButton.Value == 1)
                app.TextArea.Value = '';
                imshow(app.MatBinary, 'Parent', app.UIAxes)
            
            % One pixel thin image
            elseif (app.OnepixelThinImageButton.Value == 1)
                app.TextArea.Value = '';
                if (strcmp(app.fileID, 'charact1.txt'))
                    app.MatBinary = 1 - app.MatBinary;
                end
                app.MatSkel = skelerode(app, app.MatBinary, app.rows, app.cols); 
                if (strcmp(app.fileID, 'charact1.txt'))
                    app.MatBinary = 1 - app.MatBinary;
                    app.MatSkel = 1 - app.MatSkel;
                end
                imshow(app.MatSkel, 'Parent', app.UIAxes)
            
            % Outline image
            elseif (app.OutlineImageButton.Value == 1)
                app.TextArea.Value = '';
                app.MatOutline = outline(app, app.MatBinary, app.rows, app.cols);
                if (strcmp(app.fileID, 'charact1.txt'))
                    app.MatOutline = 1 - app.MatOutline;
                end
                imshow(app.MatOutline, 'Parent', app.UIAxes)
            
            % Segment image (4-connectivity)
            elseif (app.SegmentImage4connectivityButton.Value == 1)
                app.TextArea.Value = '';
                if (strcmp(app.fileID, 'chromo.txt'))
                    MatFlipped = 1 - app.MatBinary;
                else
                    MatFlipped = app.MatBinary;
                end
                [MatWorking, c, counttwo, countone] = FourConnect(app, MatFlipped);
                % Label different parts of the image using a custom function Label
                [app.MatSegmented, NumLabels] = Label(app, MatWorking, c, counttwo, countone);
                % Change gray to RGB and display the labeled parts 
                app.MatSegLabeled = label2rgb(app.MatSegmented, 'hsv', 'k', 'shuffle');
                imshow(app.MatSegLabeled, 'Parent', app.UIAxes)
            
            % Segment image (8-connectivity)
            elseif (app.SegmentImage8connectivityButton.Value == 1)
                app.TextArea.Value = '';
                if (strcmp(app.fileID, 'chromo.txt'))
                    MatFlipped = 1 - app.MatBinary;
                else
                    MatFlipped = app.MatBinary;
                end
                [MatWorking, c, counttwo, countone] = EightConnect(app, MatFlipped);
                % Label different parts of the image using a custom function Label
                [app.MatSegmented, NumLabels] = Label(app, MatWorking, c, counttwo, countone);
                % Change gray to RGB and display the labeled parts 
                app.MatSegLabeled = label2rgb(app.MatSegmented, 'hsv', 'k', 'shuffle');
                imshow(app.MatSegLabeled, 'Parent', app.UIAxes)
                
            % Arrange image
            elseif (app.ArrangeImageButton.Value == 1)
                if (strcmp(app.fileID, 'charact1.txt'))
                    app.TextArea.Value = '';
                    MatFlipped = app.MatBinary;
                    [MatWorking, c, counttwo, countone] = FourConnect(app, MatFlipped);
                    % Label different parts of the image using a custom function Label
                    [app.MatSegmented, NumLabels] = Label(app, MatWorking, c, counttwo, countone);
                    charMeasurements = regionprops(app.MatSegmented,'all');
                    t = [];
                    for u = 1: NumLabels
                        s = size(charMeasurements(u).Image);
                        t(end+1) = s(1);
                    end
                    % Check the maximum height of all parts and complement other parts to this
                    % height
                    h = max(t);
                    arrImg = zeros(h,2);
                    for u = 1: NumLabels
                        s = size(charMeasurements(u).Image);
                        if s(1) < h
                            charMeasurements(u).Image = [zeros((h-s(1)), s(2)); [charMeasurements(u).Image]];
                        end
                    end
                    % Joint complemented parts by the required sequence: AB123C
                    app.MatArranged = cat(2, arrImg, charMeasurements(5).Image, zeros(h,2), charMeasurements(6).Image, zeros(h,2),...
                                            charMeasurements(1).Image, zeros(h,2), charMeasurements(2).Image, zeros(h,2),...
                                            charMeasurements(3).Image, zeros(h,2), charMeasurements(4).Image, zeros(h,2));
                    imshow(app.MatArranged, 'Parent', app.UIAxes) 
                else
                    app.TextArea.Value = 'This selected image is wrong. Please select Image 2';
                end
                
            
            % Rotate image
            elseif(app.RotateImageButton.Value == 1)
                if (size(app.MatArranged) == 0)
                    app.TextArea.Value = 'Please select Image 2, and do Arrange Image Task first';
                else
                    app.TextArea.Value = '';
                    % By using nearest neighbour interpolation, we rotate the image about its
                    % center point
                    [arrangedcols,arrangedrows]=size(app.MatArranged);
                    angle = deg2rad(30);
                    
                    % Because rotating the image will result in a larger image, we set the new
                    % image size to be the maximum possible first (a square output that is the
                    % higher of the two numbers)
                    newsize = max([arrangedcols,arrangedrows]);
                    
                    % For each pixel of the new image, we find the corresponding nearest pixel
                    % in the original image and set it to that value
                    for x = 1:newsize
                       for y = 1:newsize
                          TempOne = uint16((x-newsize/2)*cos(angle)+(y-newsize/2)*sin(angle)+arrangedcols/2);
                          TempTwo = uint16(-(x-newsize/2)*sin(angle)+(y-newsize/2)*cos(angle)+arrangedrows/2);
                          
                          % Check to see if the pixel is within the boundary of the image
                          if ((TempOne>0) && (TempOne<=arrangedcols) && (TempTwo>0) && (TempTwo<=arrangedrows))        
                             app.MatRotated(x,y,:) = app.MatArranged(TempOne, TempTwo, :);
                          end
                       end
                    end
                    imshow(app.MatRotated, 'Parent', app.UIAxes)
                end
            
            end
                   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileBrowserButton = uibutton(app.UIFigure, 'push');
            app.FileBrowserButton.ButtonPushedFcn = createCallbackFcn(app, @FileBrowserButtonPushed, true);
            app.FileBrowserButton.Text = 'File Browser';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.Editable = 'off';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.NoofRowsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoofRowsEditFieldLabel = uilabel(app.UIFigure);
            app.NoofRowsEditFieldLabel.Text = 'No. of Rows';
            app.NoofColumnsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoofColumnsEditFieldLabel = uilabel(app.UIFigure);
            app.NoofColumnsEditFieldLabel.Text = 'No. of Columns';
            app.PleaseSettheThresholdingLevelEditField = uieditfield(app.UIFigure, 'numeric');
            app.PleaseSettheThresholdingLevelEditField.ValueChangedFcn = createCallbackFcn(app, @PleaseSettheThresholdingLevelEditFieldValueChanged, true);
            app.PleaseSettheThresholdingLevelEditFieldLabel = uilabel(app.UIFigure);
            app.PleaseSettheThresholdingLevelEditFieldLabel.Text = 'Please Set the Thresholding Level';
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Text = 'Original Image';
            app.OriginalImage = uiimage(app.UIFigure);
            app.YourselectionisEditFieldLabel = uilabel(app.UIFigure);
            app.YourselectionisEditFieldLabel.Text = 'Your selection is ';
            app.YourselectionisEditField = uieditfield(app.UIFigure, 'text');
            app.SelectTaskButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectTaskButtonGroup.Title = 'Select Task';
            app.BinaryImageButton = uitogglebutton(app.SelectTaskButtonGroup);
            app.BinaryImageButton.Text = 'Binary Image';
            app.BinaryImageButton.Value = true;
            app.OnepixelThinImageButton = uitogglebutton(app.SelectTaskButtonGroup);
            app.OnepixelThinImageButton.Text = 'One-pixel Thin Image';
            app.OutlineImageButton = uitogglebutton(app.SelectTaskButtonGroup);
            app.OutlineImageButton.Text = 'Outline Image';
            app.SegmentImage4connectivityButton = uitogglebutton(app.SelectTaskButtonGroup);
            app.SegmentImage4connectivityButton.Text = 'Segment Image (4-connectivity)';
            app.ArrangeImageButton = uitogglebutton(app.SelectTaskButtonGroup);
            app.ArrangeImageButton.Text = 'Arrange Image';
            app.RotateImageButton = uitogglebutton(app.SelectTaskButtonGroup);
            app.RotateImageButton.Text = 'Rotate Image';
            app.SegmentImage8connectivityButton = uitogglebutton(app.SelectTaskButtonGroup);
            app.SegmentImage8connectivityButton.Text = 'Segment Image (8-connectivity)';
            app.ForImage1andImage2Label = uilabel(app.SelectTaskButtonGroup);
            app.ForImage1andImage2Label.Text = 'For Image 1 and Image 2';
            app.ForImage1OnlyLabel = uilabel(app.SelectTaskButtonGroup);
            app.ForImage1OnlyLabel.Text = 'For Image 1 Only';
            app.ForImage2OnlyLabel = uilabel(app.SelectTaskButtonGroup);
            app.ForImage2OnlyLabel.Text = 'For Image 2 Only';
            app.OutputButton = uibutton(app.UIFigure, 'push');
            app.OutputButton.ButtonPushedFcn = createCallbackFcn(app, @OutputButtonPushed, true);
            app.OutputButton.Text = 'OUTPUT';
            app.OutputImagePanel = uipanel(app.UIFigure);
            app.OutputImagePanel.Title = 'Output Image';
            app.UIAxes = uiaxes(app.OutputImagePanel);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.RecommendedThresholdingLevelLabel = uilabel(app.UIFigure);
            app.RecommendedThresholdingLevelLabel.Text = 'Recommended Thresholding Level:';
            app.Image1chromotxt21Label = uilabel(app.UIFigure);
            app.Image1chromotxt21Label.Text = 'Image 1 (chromo.txt):       21';
            app.Image2charact1txt3Label = uilabel(app.UIFigure);
            app.Image2charact1txt3Label.Text = 'Image 2 (charact1.txt):       3';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.TextArea = uitextarea(app.Panel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = me5405_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end